classdef mandelbrotFractals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ZoomSlider       matlab.ui.control.Slider
        ZoomSliderLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            
            MAX_NUM_OF_COMPUTATIONS = 200;
            DRAW_AROUND_RE = -1.46561237;
            DRAW_AROUND_IM = 0.00002221;

            zoom = app.ZoomSlider.Value;
            step = 0.02;
            realPartPlot = (-2/zoom)-DRAW_AROUND_IM:(step/zoom):(1/zoom)-DRAW_AROUND_RE;
            imaginaryPartPlot = (-1/zoom)-DRAW_AROUND_IM:(step/zoom):(1/zoom)-DRAW_AROUND_IM;

            hold (app.UIAxes, 'on')
            % set(app.UIAxes,'color', 'blue');

            for re = realPartPlot
                for im = imaginaryPartPlot
                    c = re + im*1i;
                    deviation = mandelbrotCheck(c, MAX_NUM_OF_COMPUTATIONS);
                    if(deviation == MAX_NUM_OF_COMPUTATIONS)
                        plot(app.UIAxes, re, im, 'black.')      
                    elseif(deviation > 2*(MAX_NUM_OF_COMPUTATIONS/3) && deviation < MAX_NUM_OF_COMPUTATIONS)
                        plot(app.UIAxes, re, im, 'red.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/2 && deviation < 2*(MAX_NUM_OF_COMPUTATIONS/3))
                        plot(app.UIAxes, re, im, 'magenta.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/3 && deviation < MAX_NUM_OF_COMPUTATIONS/2)
                        plot(app.UIAxes, re, im, 'yellow.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/8 && deviation < MAX_NUM_OF_COMPUTATIONS/3)
                        plot(app.UIAxes, re, im, 'green.')
                    elseif(deviation > MAX_NUM_OF_COMPUTATIONS/16 && deviation < MAX_NUM_OF_COMPUTATIONS/8)
                        plot(app.UIAxes, re, im, 'cyan.')
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Mandelbrot fractals'; ''})
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            zlabel(app.UIAxes, 'Z')
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [1 100];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mandelbrotFractals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end